classdef speed_up_video_v01_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        video_speed_up        matlab.ui.Figure
        axes1                 matlab.ui.control.UIAxes
        status                matlab.ui.control.Label
        load                  matlab.ui.control.Button
        uipanel1              matlab.ui.container.Panel
        InputvideofileLabel   matlab.ui.control.Label
        metadata              matlab.ui.control.TextArea
        OutputvideofileLabel  matlab.ui.control.Label
        metadata_out          matlab.ui.control.TextArea
        options               matlab.ui.container.ButtonGroup
        radiobutton1          matlab.ui.control.RadioButton
        radiobutton2          matlab.ui.control.RadioButton
        radiobutton3          matlab.ui.control.RadioButton
        interval              matlab.ui.control.EditField
        text5                 matlab.ui.control.Label
        duration              matlab.ui.control.EditField
        text6                 matlab.ui.control.Label
        speed_factor          matlab.ui.control.EditField
        text7                 matlab.ui.control.Label
        process               matlab.ui.control.Button
        filename              matlab.ui.control.EditField
        text9                 matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
    end


    properties (Access = private)
        filetypes={'*.avi;*.mp4;*mpg;*.wmv;*.mov'}; % filetypes the app accepts
        abort_state=0;% abort state
        pathname=[];% default pathname
        file_in=[];% default filename
        v_in=[];% original video object
        v_out=[];%output video
        option=1;%default options setting
    end
    
    methods (Access = private)
        function abort(app, fout, f1)
            % This fcn determines whether or not to abort the processing procedure
            if isnumeric(fout)
                app.abort_state=1;
                return
            elseif f1>90
                app.status.Text=...
                    ['Status: Frame rate, ',num2str(f1,2),...
                    ' fps, is too high (fps must be < 90)! Please choose another option.'];
                app.abort_state=1;
                return
            else
                app.abort_state=0;
            end
        end
        
        function change_fps(app, f1, fout)
            % Speed up video based on changing the fps
            
            % Turn off hardware acceleration
            matlab.video.read.UseHardwareAcceleration('off')
            %Create a VideoReader object
            Vidobj = VideoReader([app.pathname,app.file_in]);
            % Turn back on the hardware acceleration
            matlab.video.read.UseHardwareAcceleration('on')
            
            % Create video object for the processed video
            [~,name,ext]=fileparts(fout);
            profile=id_profile(app,ext);
            v_out=VideoWriter([app.pathname,name],profile);
            v_out.FrameRate=f1;%set process video to target frame rate
            open(v_out);
            
            % estimate the number of frames in the original video
            n=Vidobj.Duration*Vidobj.FrameRate;
            
            % Create progress bar
            set(app.axes1,'xlim',[0 n],'xtick',[],'ytick',[],'ylim',[0 1],...
                'xcolor','none','ycolor','none');
            b=patch(app.axes1,[0 1 1 0 0],[0 0 1 1 0],'g','facecolor',[0 0.5 0],...
                'edgecolor',[0 0.5 0]);
            
            count=1;%counter for while loop
            while hasFrame(Vidobj)
                frame=readFrame(Vidobj);
                writeVideo(v_out,frame);
                b.XData(2:3)=ones(1,2).*count;
                count=count+1;
            end
            b.XData(2:3)=ones(1,2).*n;%fill up the entire bar
            close(v_out);
            app.v_out=v_out;
        end
        
        function fout = format_filename(app)
            % This fcn ensures that the processed video filename is acceptable.
            
            fout0=app.filename.Value;
            [~,fout0_name,~]=fileparts(fout0);
            
            % Check processed video file if it follows MATLAB naming rules
            if ~isvarname(fout0_name)% if not, ask user what to do
                app.status.Text=...
                    'Warning: Output video file does not follow MATLAB naming rules!';
                quest=['\fontsize{12}',...
                    'Output video file does not follow MATLAB naming rules! ',...
                    'Make sure the filename does not begin with a number and contain ',...
                    'special characters (!, @, \_, etc.). ',...
                    'The output filename will be renamed as ''video\_out.mp4''. ',...
                    'Do you wish to continue?'];
                title='Warning!';
                opts.Default='<html><font size="4">Yes';
                opts.Interpreter='TeX';
                answer = questdlg(quest,title,...
                    '<html><font size="4">Yes','<html><font size="4">No',opts);
                if isempty(answer)
                    fout=0;
                    app.status.Text='Status: Cancelled video processing.';
                    return
                end
                switch answer
                    case '<html><font size="4">Yes'
                        fout0='video_out.mp4';
                        app.filename.Value=fout0;
                        app.status.Text='Status: File renamed to video_out.mp4';
                    case '<html><font size="4">No'
                        fout=0;
                        app.status.Text='Status: Cancelled video processing.';
                        return
                end
            
            end
            
            [~,filename,ext]=fileparts(fout0);% parse filename
            if isempty(ext)%if user did not specify an video extension
                app.status.Text='Status: Filetype set as .mp4';
                if isempty(filename)
                    fout=[filename,'.mp4'];
            
                else
                    fout=[fout0,'.mp4'];
                end
            
            else%check if ext is acceptable
                if strcmp(ext,'.avi')||strcmp(ext,'.mp4')
                    fout=[fout0,ext];
            
                else%if it is not acceptable use mp4 ext
                    app.status.Text=...
                        ['Status: Unrecognized output filetype.',...
                        'Filetype set as .mp4.'];
                    fout=[filename,'.mp4'];
                    app.filename.Value=fout;
                end
            
            end
        end
        
        function metadata = get_meta(app, v)
            % get metadata from VideoReader object
            metadata0=[...
                {'Filename:    ',v.Name};...
                {'Path:    ',v.Path};...
                {'Duration (s):    ',v.Duration};...
                {'FrameRate:    ',v.FrameRate};...
                {'Height:    ',v.Height};...
                {'Width:    ',v.Width};...
                {'BitsPerPixel:    ',v.BitsPerPixel};...
                {'VideoFormat:    ',v.VideoFormat};...
                ];
            for dum=1:length(metadata0)
                if ~ischar(metadata0{dum,2})
                    key=num2str(metadata0{dum,2});
                else
                    key=metadata0{dum,2};
                end
                metadata{dum}=[metadata0{dum,1},key];
            end
        end
        
        function metadata = get_meta2(app, v)
            % get metadata from ViderWriter object
            metadata0=[...
                {'Filename:    ',v.Filename};...
                {'Path:    ',v.Path};...
                {'FileFormat:    ',v.FileFormat};...
                {'Duration (s):    ',v.Duration};...
                {'FrameRate:    ',v.FrameRate};...
                {'ColorChannels:    ',v.ColorChannels};...
                {'Height:    ',v.Height};...
                {'Width:    ',v.Width};...
                {'FrameCount:    ',v.FrameCount};...
                {'VideoBitsPerPixel:    ',v.VideoBitsPerPixel};...
                {'VideoFormat:    ',v.VideoFormat};...
                {'VideoCompressionMethod:    ',v.VideoCompressionMethod};...
                {'Quality:    ',v.Quality};...
                ];
            for dum=1:length(metadata0)
                if ~ischar(metadata0{dum,2})
                    key=num2str(metadata0{dum,2});
                else
                    key=metadata0{dum,2};
                end
                metadata{dum}=[metadata0{dum,1},key];
            end
        end
        
        function profile = id_profile(app, ext)
            switch ext
                case '.avi'
                    profile='Motion JPEG AVI';
                case '.mp4'
                    profile='MPEG-4';
            end
        end
        
        function app=option1(app)
            interval=str2double(app.interval.Value);
            fout=format_filename(app); % get filename
            if isnumeric(fout)
                app.abort_state=1;
                return
            else
                app.abort_state=0;
            end
            
            % CREATE A VIDEO OBJECT FOR ORIGINAL VIDEO
            % Turn off hardware acceleration
            matlab.video.read.UseHardwareAcceleration('off')
            %Create a VideoReader object
            Vidobj = VideoReader([app.pathname,app.file_in]);
            % Turn back on the hardware acceleration
            matlab.video.read.UseHardwareAcceleration('on')
            
            % Create video object for the processed video
            [~,name,ext]=fileparts(fout);
            profile=id_profile(app,ext);
            v_out=VideoWriter([app.pathname,name],profile);
            v_out.FrameRate=Vidobj.FrameRate;
            open(v_out);
            
            % estimate the number of frames in the original video
            n=Vidobj.Duration*Vidobj.FrameRate;
            
            % Create progress bar
            set(app.axes1,'xlim',[0 n],'xtick',[],'ytick',[],'ylim',[0 1],...
                'xcolor','none','ycolor','none');
            b=patch(app.axes1,[0 1 1 0 0],[0 0 1 1 0],'g','facecolor',[0 0.5 0],...
                'edgecolor',[0 0.5 0]);
            
            count=1;%counter for while loop
            count2=1;% number of frame written
            while hasFrame(Vidobj)%play through entire movie
                frame=readFrame(Vidobj);%current frame
            
                if mod(count,interval)==1
                    app.status.Text=['Status: ',num2str(count2),' frames written'];
                    writeVideo(v_out,frame);
                    b.XData(2:3)=ones(1,2).*count;
                    count2=count2+1;
                end
            
                count=count+1;
            end
            b.XData(2:3)=ones(1,2).*n;%fill up the entire bar
            
            close(v_out);
            app.v_out=v_out;
        end
        
        function option2(app)
            duration=str2double(app.duration.Value);%target duration in s
            duration0=app.v_in.Duration;%original duration in s
            ratio=duration/duration0;%ratio between the two duration length
            f1=app.v_in.FrameRate/ratio;%determine target frame rate
            
            fout=format_filename(app);%get filename
            abort(app,fout,f1);%check whether or not to abort
            if app.abort_state==0
                change_fps(app, f1, fout);% run processing algorithm based on changing fps
            end
        end
        
        function option3(app)
            duration0=app.v_in.Duration;%original duration in s
            duration=duration0/str2double(app.speed_factor.Value);%target duration (s)
            ratio=duration/duration0;%ratio between the two duration length
            f1=app.v_in.FrameRate/ratio;%determine target frame rate
            
            fout=format_filename(app);%get filename
            abort(app,fout,f1);%check whether or not to abort
            if app.abort_state==0
                change_fps(app, f1, fout);% run processing algorithm based on changing fps
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function speed_up_video_v01_OpeningFcn(app)

        end

        % Button pushed function: load
        function load_Callback(app, event)
            app.status.Text='Status: Select video file to process';
            [filename,pathname,~]=uigetfile(app.filetypes,...
                'Select video file to process',...
                app.pathname,'MultiSelect','off');
            app.video_speed_up.Visible='off';
            app.video_speed_up.Visible='on';
            
            if filename==0
                app.status.Text='Status: no video file selected';
                return
            end
            
            app.pathname=pathname;%update pathname directory
            app.file_in=filename;%store video filename
            
            % CREATE A VIDEO OBJECT
            % Turn off hardware acceleration
            matlab.video.read.UseHardwareAcceleration('off')
            %Create a VideoReader object
            app.v_in = VideoReader([pathname,filename]);%store video object
            % Turn back on the hardware acceleration
            matlab.video.read.UseHardwareAcceleration('on')
            
            % Output metadata information
            metadata=get_meta(app,app.v_in);
            
            % update handles structure
            app.metadata.Value=metadata;
            app.status.Text=['Status: ',filename,' selected'];

        end

        % Button pushed function: process
        function process_Callback(app, event)
            app.status.Text='Status: Processing video';
            radios=app.options.Buttons;
            options=[radios(1).Value radios(2).Value radios(3).Value];
            option=find(options==1);
            cla(app.axes1);
            app.metadata_out.Value=[' '];
            
            switch option
                case 1% option 1, record every _ frames
                    app=option1(app);
                case 2% option 2, output duration _ s
                    option2(app);
                case 3% option 3, spped up by _ times
                    option3(app);
            end
            
            if app.abort_state==1
                return
            end
            metadata=get_meta2(app,app.v_out);
            app.metadata_out.Value=metadata;
            app.status.Text='Status: Video processed succesfully';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.video_speed_up = uifigure;
            app.video_speed_up.Name = 'speed_up_video_v01';
            app.axes1 = uiaxes(app.video_speed_up);
            app.axes1.XTick = zeros(1,0);
            app.axes1.YTick = zeros(1,0);
            app.status = uilabel(app.video_speed_up);
            app.status.Text = 'Status: Ready';
            app.load = uibutton(app.video_speed_up, 'push');
            app.load.ButtonPushedFcn = createCallbackFcn(app, @load_Callback, true);
            app.load.Text = 'Step 1: Load video';
            app.uipanel1 = uipanel(app.video_speed_up);
            app.uipanel1.Title = 'Metadata';
            app.InputvideofileLabel = uilabel(app.uipanel1);
            app.InputvideofileLabel.Text = 'Input video file';
            app.metadata = uitextarea(app.uipanel1);
            app.OutputvideofileLabel = uilabel(app.uipanel1);
            app.OutputvideofileLabel.Text = 'Output video file';
            app.metadata_out = uitextarea(app.uipanel1);
            app.options = uibuttongroup(app.video_speed_up);
            app.options.Title = 'Step 3: Choose an option';
            app.radiobutton1 = uiradiobutton(app.options);
            app.radiobutton1.Text = 'Record every';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.options);
            app.radiobutton2.Text = 'Output duration';
            app.radiobutton3 = uiradiobutton(app.options);
            app.radiobutton3.Text = 'Speed up by ';
            app.interval = uieditfield(app.options, 'text');
            app.interval.Value = '10';
            app.text5 = uilabel(app.options);
            app.text5.Text = 'frames';
            app.duration = uieditfield(app.options, 'text');
            app.duration.Value = '60';
            app.text6 = uilabel(app.options);
            app.text6.Text = 's';
            app.speed_factor = uieditfield(app.options, 'text');
            app.speed_factor.Value = '10';
            app.text7 = uilabel(app.options);
            app.text7.Text = 'times';
            app.process = uibutton(app.video_speed_up, 'push');
            app.process.ButtonPushedFcn = createCallbackFcn(app, @process_Callback, true);
            app.process.Text = 'Step 4: Process';
            app.filename = uieditfield(app.video_speed_up, 'text');
            app.filename.Value = '<filename>';
            app.text9 = uilabel(app.video_speed_up);
            app.text9.Text = 'Step 2:';
            app.TextArea = uitextarea(app.video_speed_up);
            app.TextArea.Value = {'Updated on 18/05/09'; 'Created on 18/05/09'; 'Created by Josh Yeh'; 'josh.yeh@espci.fr'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = speed_up_video_v01_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.video_speed_up)

            % Execute the startup function
            runStartupFcn(app, @speed_up_video_v01_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.video_speed_up)
        end
    end
end